classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        JPEGCompressionandDecompressionLabel  matlab.ui.control.Label
        PlotImageSwitch                matlab.ui.control.Switch
        PlotImageSwitchLabel           matlab.ui.control.Label
        SaveFileSwitch                 matlab.ui.control.Switch
        SaveFileSwitchLabel            matlab.ui.control.Label
        QuantizationValueQualitySpinner  matlab.ui.control.Spinner
        QuantizationValueQualityLabel  matlab.ui.control.Label
        StartProcessButton             matlab.ui.control.Button
        EnterOutputFileNameEditField   matlab.ui.control.EditField
        EnterOutputFileNameEditFieldLabel  matlab.ui.control.Label
        ChooseImageButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseImageButton
        function ChooseImageButtonPushed(app, event)
            global Path;
            [Path,~] = imgetfile();

            imshow(Path,'Parent',app.UIAxes);

        end

        % Button pushed function: StartProcessButton
        function StartProcessButtonPushed(app, event)

            global Path;
            Main(Path, app.EnterOutputFileNameEditField.Value, app.QuantizationValueQualitySpinner.Value, app.PlotImageSwitch.Value, app.SaveFileSwitch.Value)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Uncompressed Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChooseImageButton = uibutton(app.GridLayout, 'push');
            app.ChooseImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseImageButtonPushed, true);
            app.ChooseImageButton.Text = 'Choose Image';
            app.EnterOutputFileNameEditFieldLabel = uilabel(app.GridLayout);
            app.EnterOutputFileNameEditFieldLabel.Text = 'Enter Output File Name:';
            app.EnterOutputFileNameEditField = uieditfield(app.GridLayout, 'text');
            app.EnterOutputFileNameEditField.Value = 'Output_File';
            app.StartProcessButton = uibutton(app.GridLayout, 'push');
            app.StartProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessButtonPushed, true);
            app.StartProcessButton.Text = 'Start Process';
            app.QuantizationValueQualityLabel = uilabel(app.GridLayout);
            app.QuantizationValueQualityLabel.Text = 'Quantization Value (Quality):';
            app.QuantizationValueQualitySpinner = uispinner(app.GridLayout);
            app.QuantizationValueQualitySpinner.Limits = [3 8];
            app.QuantizationValueQualitySpinner.Value = 3;
            app.SaveFileSwitchLabel = uilabel(app.GridLayout);
            app.SaveFileSwitchLabel.Text = 'Save File';
            app.SaveFileSwitch = uiswitch(app.GridLayout, 'slider');
            app.SaveFileSwitch.Value = 'On';
            app.PlotImageSwitchLabel = uilabel(app.GridLayout);
            app.PlotImageSwitchLabel.Text = 'Plot Image';
            app.PlotImageSwitch = uiswitch(app.GridLayout, 'slider');
            app.PlotImageSwitch.Value = 'On';
            app.JPEGCompressionandDecompressionLabel = uilabel(app.GridLayout);
            app.JPEGCompressionandDecompressionLabel.Text = 'JPEG Compression and Decompression';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end